classdef control_pannel < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        GridLayout   matlab.ui.container.GridLayout
        Panel        matlab.ui.container.Panel
        GridLayout4  matlab.ui.container.GridLayout
        E2Panel      matlab.ui.container.Panel
        GridLayout3  matlab.ui.container.GridLayout
        E2floor      matlab.ui.control.Label
        E2direction  matlab.ui.control.Image
        E1Panel      matlab.ui.container.Panel
        GridLayout2  matlab.ui.container.GridLayout
        E1floor      matlab.ui.control.Label
        E1direction  matlab.ui.control.Image
        up           matlab.ui.control.Image
        down         matlab.ui.control.Image
        E1           matlab.ui.control.Image
        E2           matlab.ui.control.Image
        tag          matlab.ui.control.Image
        Button       matlab.ui.control.Button
    end

    
    properties (Access = public)
        cc % Description
        id
    end
    
    methods (Access = public)
        
        function setid(app,id)
            app.id=id;
            %app.E1direction.Value='setid';
            if app.id == 1
                app.down.Visible = 'off';
                app.down.Enable = 'off';
                %app.E1direction.Value='id is 1';
                app.tag.ImageSource = 'F1.png';
            elseif app.id == 3
                app.up.Visible = 'off';
                app.up.Enable = 'off';
                %app.E1direction.Value='id is 3';
                app.tag.ImageSource = 'F3.png';
            else
                app.tag.ImageSource = 'F2.png';
            end            
            
            app.resize();
        end
        
        function resize(app)
            sr = get(0,'ScreenSize');
            sw = sr(3);
            sh = sr(4);
            
            if app.id == 1
                app.UIFigure.Position=[sw/3,sh/7,sw*4/13,sh/6];
            elseif app.id ==2
                app.UIFigure.Position=[sw/3,sh*3/7,sw*4/13,sh/6];
            else
                app.UIFigure.Position=[sw/3,sh*5/7,sw*4/13,sh/6];
            end
            
        end
        
        function test(app)
            sr = get(0,'ScreenSize');
            sw = sr(3);
            sh = sr(4);

            app.UIFigure.Position=[sw/3,sh*3/7,sw*4/13,sh/6];
            
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            app.E1.ImageSource='close.png';
            app.E2.ImageSource='close.png';
            app.E1direction.ImageSource="stop.png";
            app.E2direction.ImageSource="stop.png";
            app.E1floor.Text="1";
            app.E2floor.Text="1";
            app.up.ImageSource='up_off.png';
            app.down.ImageSource='down_off.png';
        end

        % Callback function
        function downButtonPushed(app, event)
            app.down.BackgroundColor=[1,0,0];
            app.cc.callelevator(app.id,-1);
        end

        % Callback function
        function upButtonPushed(app, event)
            app.up.BackgroundColor=[1,0,0];
            app.cc.callelevator(app.id,1);
        end

        % Callback function
        function debugButtonPushed(app, event)
             app.cc.debug();
        end

        % Image clicked function: up
        function upImageClicked(app, event)
            app.up.ImageSource='up_on.png';
            app.cc.callelevator(app.id,1);
        end

        % Image clicked function: down
        function downImageClicked(app, event)
            app.down.ImageSource='down_on.png';
            app.cc.callelevator(app.id,-1);
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            app.cc.debug();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.Panel = uipanel(app.GridLayout);
            app.GridLayout4 = uigridlayout(app.Panel);
            app.E2Panel = uipanel(app.GridLayout4);
            app.E2Panel.Title = 'E2';
            app.GridLayout3 = uigridlayout(app.E2Panel);
            app.E2floor = uilabel(app.GridLayout3);
            app.E2floor.Text = 'Label4';
            app.E2direction = uiimage(app.GridLayout3);
            app.E1Panel = uipanel(app.GridLayout4);
            app.E1Panel.Title = 'E1';
            app.GridLayout2 = uigridlayout(app.E1Panel);
            app.E1floor = uilabel(app.GridLayout2);
            app.E1floor.Text = 'Label3';
            app.E1direction = uiimage(app.GridLayout2);
            app.up = uiimage(app.GridLayout4);
            app.up.ImageClickedFcn = createCallbackFcn(app, @upImageClicked, true);
            app.down = uiimage(app.GridLayout4);
            app.down.ImageClickedFcn = createCallbackFcn(app, @downImageClicked, true);
            app.E1 = uiimage(app.GridLayout);
            app.E2 = uiimage(app.GridLayout);
            app.tag = uiimage(app.GridLayout);
            app.Button = uibutton(app.GridLayout, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Enable = 'off';
            app.Button.Visible = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = control_pannel

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end