classdef elevatorUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure    matlab.ui.Figure
        GridLayout  matlab.ui.container.GridLayout
        floor       matlab.ui.control.LinearGauge
        status      matlab.ui.control.Label
        lamp        matlab.ui.control.Lamp
        Image       matlab.ui.control.Image
        pannel      matlab.ui.container.ButtonGroup
        F3          matlab.ui.control.Button
        F2          matlab.ui.control.Button
        F1          matlab.ui.control.Button
        open        matlab.ui.control.Button
        close       matlab.ui.control.Button
        stop        matlab.ui.control.Button
        Label       matlab.ui.control.Label
    end

    
    properties (Access = public)
        cc % Description
        id
                
    end
    
    methods (Access = public)
        function resize(app)
            sr = get(0,'ScreenSize');
            sw = sr(3);
            sh = sr(4);
            
            if app.id == 1
                app.UIFigure.Position=[sw/8,sh/5,sw/8,sh*2/3];
            else
                app.UIFigure.Position=[sw*6/8,sh/5,sw/8,sh*2/3];
            end
            
        end
    end
    
    methods (Access = private)
        
        function disableall(app)
           app.F3.Enable='off';
           app.F2.Enable='off';
           app.F1.Enable='off';
           app.open.Enable='off';
           app.close.Enable='off';
        end
        
        function enableall(app)
           app.F3.Enable='on';
           app.F2.Enable='on';
           app.F1.Enable='on';
           app.open.Enable='on';
           app.close.Enable='on';
        end


        function test(app)
            sr = get(0,'ScreenSize');
            sw = sr(3);
            sh = sr(4);
            
            
            app.UIFigure.Position=[sw*6/8,sh/5,sw/8,sh*2/3];
                      
        end
    end
   

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Image.ImageSource='close.png';
            
            
        end

        % Button pushed function: F3
        function F3Pushed(app, event)
            app.F3.BackgroundColor=[1,0,0];
            app.cc.goto(app.id,3);
        end

        % Button pushed function: F2
        function F2Pushed(app, event)
            app.F2.BackgroundColor=[1,0,0];
            app.cc.goto(app.id,2);
        end

        % Button pushed function: F1
        function F1Pushed(app, event)
            app.F1.BackgroundColor=[1,0,0];
            app.cc.goto(app.id,1);
        end

        % Button pushed function: open
        function openPushed(app, event)
            app.open.BackgroundColor=[1,0,0];
            app.cc.open(app.id);
        end

        % Button pushed function: close
        function closePushed(app, event)
            app.close.BackgroundColor=[1,0,0];
            app.cc.close(app.id);
        end

        % Button pushed function: stop
        function stopPushed(app, event)
            if (app.stop.BackgroundColor==[0.96,0.96,0.96])
                disp("stoped!")
                app.cc.emergency(app.id);
                app.disableall(); 
            elseif (app.stop.BackgroundColor==[1,1,1])
                disp("stoped!")
                app.cc.emergency(app.id);
                app.disableall(); 
            else
                app.enableall();
                app.cc.restart(app.id);
                disp("restart!")
            end


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.floor = uigauge(app.GridLayout, 'linear');
            app.floor.Limits = [1 3];
            app.floor.MajorTicks = [1 2 3];
            app.floor.MinorTicks = [1 2 3];
            app.status = uilabel(app.GridLayout);
            app.status.Text = 'Label2';
            app.lamp = uilamp(app.GridLayout);
            app.Image = uiimage(app.GridLayout);
            app.pannel = uibuttongroup(app.GridLayout);
            app.F3 = uibutton(app.pannel, 'push');
            app.F3.ButtonPushedFcn = createCallbackFcn(app, @F3Pushed, true);
            app.F3.Text = 'F3';
            app.F2 = uibutton(app.pannel, 'push');
            app.F2.ButtonPushedFcn = createCallbackFcn(app, @F2Pushed, true);
            app.F2.Text = 'F2';
            app.F1 = uibutton(app.pannel, 'push');
            app.F1.ButtonPushedFcn = createCallbackFcn(app, @F1Pushed, true);
            app.F1.Text = 'F1';
            app.open = uibutton(app.pannel, 'push');
            app.open.ButtonPushedFcn = createCallbackFcn(app, @openPushed, true);
            app.open.Text = 'open';
            app.close = uibutton(app.pannel, 'push');
            app.close.ButtonPushedFcn = createCallbackFcn(app, @closePushed, true);
            app.close.Text = 'close';
            app.stop = uibutton(app.pannel, 'push');
            app.stop.ButtonPushedFcn = createCallbackFcn(app, @stopPushed, true);
            app.stop.Text = 'stop';
            app.Label = uilabel(app.GridLayout);
            app.Label.Text = 'Label3';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = elevatorUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end